classdef MoCap_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        pnlCalibrate                  matlab.ui.container.Panel
        GetCalibrationDataButton      matlab.ui.control.Button
        LoadCalibrationButton         matlab.ui.control.Button
        SaveCalibrationButton         matlab.ui.control.Button
        RatingLabel                   matlab.ui.control.Label
        LastCalibrationLabel          matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        CameraPreviewButton           matlab.ui.control.Button
        pnlViewing                    matlab.ui.container.Panel
        chkTrackingLines              matlab.ui.control.CheckBox
        chkDroneBody                  matlab.ui.control.CheckBox
        ThreeD                        matlab.ui.control.UIAxes
        btnTracking                   matlab.ui.control.Button
        pnlOmegaTime                  matlab.ui.container.Panel
        OrientationvsTimeGraphsLabel  matlab.ui.control.Label
        AngularVelocityvsTimeGraphsLabel  matlab.ui.control.Label
        ZOrientTime                   matlab.ui.control.UIAxes
        YOrientTime                   matlab.ui.control.UIAxes
        XOrientTime                   matlab.ui.control.UIAxes
        ZOmegaTime                    matlab.ui.control.UIAxes
        YOmegaTime                    matlab.ui.control.UIAxes
        XOmegaTime                    matlab.ui.control.UIAxes
        pnlPosTime                    matlab.ui.container.Panel
        VelocityvsTimeGraphsLabel     matlab.ui.control.Label
        PositionvsTimeGraphsLabel     matlab.ui.control.Label
        ZVelTime                      matlab.ui.control.UIAxes
        YVelTime                      matlab.ui.control.UIAxes
        XVelTime                      matlab.ui.control.UIAxes
        ZPosTime                      matlab.ui.control.UIAxes
        YPosTime                      matlab.ui.control.UIAxes
        XPosTime                      matlab.ui.control.UIAxes
        pnlPathway                    matlab.ui.container.Panel
        PathThreeD                    matlab.ui.control.UIAxes
        btnCalibrate                  matlab.ui.control.Button
        btnPathway                    matlab.ui.control.Button
        btnAngularVGraphs             matlab.ui.control.Button
        btnPosGraphs                  matlab.ui.control.Button
        btnThreeDView                 matlab.ui.control.Button
        pnlLog                        matlab.ui.container.Panel
        LogLabel                      matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        pnlHeading                    matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        MotionCaptureGUILabel         matlab.ui.control.Label
        pnlTracking                   matlab.ui.container.Panel
        EditField_12                  matlab.ui.control.EditField
        EditField_11                  matlab.ui.control.EditField
        EditField_10                  matlab.ui.control.EditField
        EditField_9                   matlab.ui.control.EditField
        EditField_8                   matlab.ui.control.EditField
        EditField_7                   matlab.ui.control.EditField
        EditField_6                   matlab.ui.control.EditField
        EditField_5                   matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
        StopTrackingButton            matlab.ui.control.Button
        zLabel                        matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        xLabel                        matlab.ui.control.Label
        EditField_4                   matlab.ui.control.EditField
        radsLabel                     matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        OrientationLabel              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        VelocitymsLabel               matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        PositionmLabel                matlab.ui.control.Label
        StartTrackingButton           matlab.ui.control.Button
        pnlSeen                       matlab.ui.container.Panel
        SeenTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        CameraFrustum   % handle to the patch object for when you rotate or move it
        %% Global variables
        logData string;
        numCameras double;
        SquareSizeMM double;
        CameraParams;
        %isTracking boolean;
        liveTimer timer
        drone
        trail
        isTracking logical = false;
        wantTrackingLines logical = true;
        udpReceiver
        lastTime = 0
        lastPosition = []

        %position graph stuff:
        timeHist double = []
        posHist double = []
        velHist double = []
        omegaHist double = []
        orientationHist double = []
        maxHistLength = 2000;


        %new shit

        startTime
        udp
        udp2
        cam1Position
        cam1Rotation
        cam1Updated
        cam2Position
        cam2Rotation
        cam2Updated
        % Graph update
        graphTimer
        linePosX
        linePosY
        linePosZ
        lineVelX
        lineVelY
        lineVelZ
        lineOmegaX
        lineOmegaY
        lineOmegaZ
        lineOrientX
        lineOrientY
        lineOrientZ
        droneMarkers

        SightLines = gobjects(0); % Array to store dynamic tracking lines
    end
    
    % ============================================================================================================================

    % Methods used to construct Camera and Drone objects

    methods (Access = private)
    
        function [V, F] = makeFrustum(app, posVector, orientation, tag)
        near = 0.2;   % 20 cm
            far  = 0.7;   % 2 meters (increased for visibility)
            halfWidthNear  = 0.15;
            halfHeightNear = 0.12;
            halfWidthFar   = 0.4;
            halfHeightFar  = 0.35;
        
            % Creating frustum vertices in LOCAL camera frame
            % Camera looks along +Z axis (forward)
            V_local = [
                % Near plane (closer to camera origin)
                -halfWidthNear,  -halfHeightNear,  near;   % point 1
                 halfWidthNear,  -halfHeightNear,  near;   % point 2
                 halfWidthNear,   halfHeightNear,  near;   % point 3
                -halfWidthNear,   halfHeightNear,  near;   % point 4
                
                % Far plane (further from camera)
                -halfWidthFar,   -halfHeightFar,   far;   % point 5
                 halfWidthFar,   -halfHeightFar,   far;   % point 6
                 halfWidthFar,    halfHeightFar,   far;   % point 7
                -halfWidthFar,    halfHeightFar,   far;   % point 8
            ];
        
            % Transform vertices to world coordinates
            % For each vertex: v_world = R * v_local + pos
            V_world = zeros(size(V_local));
            for j = 1:size(V_local, 1)
                V_world(j, :) = (orientation * V_local(j, :)')' + posVector;
            end
        
            % Define faces
            F = [
                1 2 3 4;   % near square
                5 6 7 8;   % far square
                1 2 6 5;   % bottom face
                2 3 7 6;   % right face
                3 4 8 7;   % top face
                4 1 5 8;   % left face
            ];
        
            % Draw frustum using faces and vertices
            patch(app.ThreeD, ...
            'Vertices', V_world, ...
            'Faces', F, ...
            'FaceColor', [0.2 0.6 1], ...
            'FaceAlpha', 0.3, ...
            'EdgeColor', [0 0.3 0.8], ...
            'LineWidth', 1.5, ...
            'Tag', tag); 
        end

        % function to create the drone body
        function drone = createDroneObject(app)

            % Ensure axis is ready
            hold(app.ThreeD, 'on');
            view(app.ThreeD, 3);
            axis(app.ThreeD, 'equal');
            grid(app.ThreeD, 'on');
        
            % Root transform (local drone frame)
            drone.tf = hgtransform('Parent', app.ThreeD);
        
            % Plane body (simplified model)
            s = 0.15;  % half-size (meters, arbitrary for now)
        
            verts = [-s -s 0;
                       s -s 0;
                       s  s 0;
                      -s  s 0];
        
            drone.plane = patch( app.ThreeD, ...
                'Parent', drone.tf, ...
                'Vertices', verts, ...
                'Faces', [1 2 3 4], ...
                'FaceColor', [0.7 0.7 0.7], ...
                'FaceAlpha', 0.6);
        
            % Reflective markers (4 spheres)
            d = 0.15;      % marker offset from center
            r = 0.03;      % marker radius
        
            markerPos = [ d  d 0;
                         -d  d 0;
                         -d -d 0;
                          d -d 0];
        
            [sx, sy, sz] = sphere(10);
        
            for k = 1:4
                drone.markers(k) = surf( app.ThreeD, ...
                    r*sx + markerPos(k,1), ...
                    r*sy + markerPos(k,2), ...
                    r*sz + markerPos(k,3), ...
                    'Parent', drone.tf, ...
                    'FaceColor', 'w', ...
                    'EdgeColor', 'none');
            end
        
            % Local coordinate axes (required)
            L = 0.3;  % axis length
        
            drone.axes.x = quiver3( ...
                0,0,0, L,0,0, ...
                'Parent', drone.tf, ...
                'Color', 'r', 'LineWidth', 2);
        
            drone.axes.y = quiver3( ...
                0,0,0, 0,L,0, ...
                'Parent', drone.tf, ...
                'Color', 'g', 'LineWidth', 2);
        
            drone.axes.z = quiver3( ...
                0,0,0, 0,0,L, ...
                'Parent', drone.tf, ...
                'Color', 'b', 'LineWidth', 2);

            % ===================================================

            % 3D body: main cuboid
            L = 0.27;   % length (X direction)
            W = 0.24;   % width  (Y direction)
            H = 0.12;   % height (Z direction)
            
            % Vertices (local drone coordinates)
            V_body = [ ...
                -L/2 -W/2 -H/2;
                 L/2 -W/2 -H/2;
                 L/2  W/2 -H/2;
                -L/2  W/2 -H/2;
                -L/2 -W/2  H/2;
                 L/2 -W/2  H/2;
                 L/2  W/2  H/2;
                -L/2  W/2  H/2 ];
            
            % Faces
            F_body = [ ...
                1 2 3 4;  % bottom
                5 6 7 8;  % top
                1 2 6 5;  % side
                2 3 7 6;
                3 4 8 7;
                4 1 5 8 ];
            
            drone.body3D = patch(app.ThreeD, ...
                'Parent', drone.tf, ...
                'Vertices', V_body, ...
                'Faces', F_body, ...
                'FaceColor', [0.4 0.4 0.8], ...
                'FaceAlpha', 0.9);

            % ===================================================

            % 3D body: nose (pyramid)
            noseLength = 0.18;
            noseHeight = 0.15;
            
            V_nose = [ ...
                 L              0        0;
                 L - noseLength -W/4    -noseHeight/2;
                 L - noseLength  W/4    -noseHeight/2;
                 L - noseLength  W/4     noseHeight/2;
                 L - noseLength -W/4     noseHeight/2 ];
            
            F_nose = [ ...
                1 2 3;
                1 3 4;
                1 4 5;
                1 5 2;
                2 3 4;
                2 4 5 ];
            
            drone.nose = patch( app.ThreeD, ...
                'Parent', drone.tf, ...
                'Vertices', V_nose, ...
                'Faces', F_nose, ...
                'FaceColor', [0.8 0.3 0.3], ...
                'FaceAlpha', 0.9);


            % Hide 3D body by default
            drone.body3D.Visible = 'off';
            drone.nose.Visible   = 'off';

        end

        % Updating the drone pos and orientation during live display
        function updateDronePose(~, drone, pos, R)
            %UPDATEDRONEPOSE Update drone pose using hgtransform
            %
            % pos : [x y z]
            % R        : 3x3 rotation matrix (local → world)
            
                T = eye(4);
                T(1:3,1:3) = R;
                T(1:3,4)   = pos(:);
            
                drone.tf.Matrix = T;
        end

        % Show plane only of drone object
        function showPlane(~, drone)
            drone.plane.Visible  = 'on';
            drone.body3D.Visible = 'off';
            drone.nose.Visible   = 'off';
        end

        % Show 3D body of drone object (rectangular prsim and wedge in front)
        function show3D(~, drone)
            drone.plane.Visible  = 'off';
            drone.body3D.Visible = 'on';
            drone.nose.Visible   = 'on';
        end

        function updateDroneFromUDP(app, src, ~)
            try
                %reading packets from UDP
                n = src.NumDatagramsAvailable;
                if n == 0, return; end
                packets = read(src, n);
        
                %getting the bytes
                rawBytes = packets(end).Data;
                if numel(rawBytes) ~= 104
                    return;
                end
                data = typecast(uint8(rawBytes), 'double');
        
                % getting the data from the 13x1 vector
                pos   = data(1:3); %pos is first 3
                vel   = data(4:6);
                quat  = data(7:10);
                omega = data(11:13);
        
                pos   = pos(:);
                vel   = vel(:);
                quat  = quat(:);
                omega = omega(:);
                
                quatRow = quat';
                % --- TIME ---
                tNow = toc(app.startTime);
        
                % this is for the plotting of the graphs
                app.timeHist(end+1,1) = tNow;
                app.posHist(end+1,:)  = pos';
                app.velHist(end+1,:)  = vel';
                app.omegaHist(end+1,:) = omega';
        
                % Trim buffers
                if size(app.timeHist,1) > app.maxHistLength
                    app.timeHist(1,:) = [];
                    app.posHist(1,:)  = [];
                    app.velHist(1,:)  = [];
                    app.omegaHist(1,:) = [];
                end
        
                % === UPDATE 3D VIEW ===
                addpoints(app.trail, pos(1), pos(2), pos(3));
        
                % live number updates:


                %positions
                app.EditField.Value   = sprintf('%.3f', pos(1));
                app.EditField_5.Value = sprintf('%.3f', pos(2));
                app.EditField_9.Value = sprintf('%.3f', pos(3));
                
                %velocity
                app.EditField_2.Value = sprintf('%.3f', vel(1));
                app.EditField_6.Value = sprintf('%.3f', vel(2));
                app.EditField_10.Value= sprintf('%.3f', vel(3));
                
                %orientation: UNDER CONSTRUCTION
                %need to sort out what these orienatation values even are
                %first 
                %w=quat(1); x=quat(2); y=quat(3); z=quat(4);
                oAng = quat2eul(quatRow);
                
                app.orientationHist(end+1, :)= oAng;

                if size(app.orientationHist,1) > app.maxHistLength
                    app.orientationHist(1,:) = [];
                end
                
                
                app.EditField_3.Value = sprintf('%.3f', oAng(1));
                app.EditField_7.Value = sprintf('%.3f', oAng(2));
                app.EditField_11.Value = sprintf('%.3f', oAng(3));
                
                %angular velocity:
                app.EditField_4.Value = sprintf('%.3f', omega(1));
                app.EditField_8.Value = sprintf('%.3f', omega(2));
                app.EditField_12.Value = sprintf('%.3f', omega(3));

                
                % --- ROTATION ---
                quat = quat / norm(quat);
                
        
                % R = [1-2*(y^2+z^2),   2*(x*y-w*z),   2*(x*z+w*y);
                %      2*(x*y+w*z),   1-2*(x^2+z^2),   2*(y*z-w*x);
                %      2*(x*z-w*y),   2*(y*z+w*x),   1-2*(x^2+y^2)];
                
                %quatRow = [quat(4) quat(1) quat(2) quat(3)];
                R = quat2rotm(quatRow);
        
                app.updateDronePose(app.drone, pos', R);

                app.updateDroneMarkers(pos', R)

                persistent updateCount;
                if isempty(updateCount), updateCount = 0; end
                updateCount = updateCount + 1;
                

                if mod(updateCount, 5) == 0 && app.wantTrackingLines
                    app.checkDroneVisibility();
                end
        
                drawnow limitrate;
        
            catch ME
                % ignore transient UDP errors
                fprintf('Error in UDP Update: %s\n', ME.message);
            end


        end
        
        function receiveCalibrationUDP(app, src)
            %CHANGE THIS FOR UNCS NEW SYSTEM
            if src.NumDatagramsAvailable == 0
                return;
            end
            
            pkt = read(src, src.NumDatagramsAvailable);
            raw = pkt(end).Data;
            
            % Expecting 12 doubles = 96 bytes
            if numel(raw) ~= 96
                return;
            end
            
            data = typecast(uint8(raw), 'double');
            
            % Rotation Matrix (3x3)
            rawR = reshape(data(1:9), 3, 3);
            
            % Translation Vector (3x1)
            rawT = data(10:12);
            if isrow(rawT)
                rawT = rawT';
            end
            
            posVector = rawT';
            
            % Correct fix from Simulink to this app
            R_fix = [0 0 1; 0 1 0; -1 0 0];
            rotMatrix = rawR * R_fix;
            
            % camera id:
            % Determine camera ID by which port received the data
            if src.LocalPort == 25001
                cameraID = 1;
                cameraTag = 'LiveCamera1';
                cameraColor = 'c';  % cyan
                cameraName = 'Cam 1';
            elseif src.LocalPort == 25002
                cameraID = 2;
                cameraTag = 'LiveCamera2';
                cameraColor = 'm';  % Mmagenta
                cameraName = 'Cam 2';
            else
                return;  
            end
            
            hold(app.ThreeD, 'on');
            
            % for later
            %delete(findobj(app.ThreeD, 'Tag', cameraTag));
            
            %drawing cameras
            axisLen = 0.5;
            origin = posVector;
            
            xAxis = origin + axisLen * rotMatrix(:,1)';
            yAxis = origin + axisLen * rotMatrix(:,2)';
            zAxis = origin + axisLen * rotMatrix(:,3)';
            
            h1 = plot3(app.ThreeD, ...
                [origin(1) xAxis(1)], ...
                [origin(2) xAxis(2)], ...
                [origin(3) xAxis(3)], ...
                'r-', 'LineWidth', 2, 'Tag', cameraTag);
            
            h2 = plot3(app.ThreeD, ...
                [origin(1) yAxis(1)], ...
                [origin(2) yAxis(2)], ...
                [origin(3) yAxis(3)], ...
                'g-', 'LineWidth', 2, 'Tag', cameraTag);
            
            h3 = plot3(app.ThreeD, ...
                [origin(1) zAxis(1)], ...
                [origin(2) zAxis(2)], ...
                [origin(3) zAxis(3)], ...
                'b-', 'LineWidth', 3, 'Tag', cameraTag);
            
            % calling the function
            app.makeFrustum(posVector, rotMatrix,cameraTag);
            
            % label
            text(app.ThreeD, ...
                origin(1), origin(2), origin(3) + 0.2, ...
                cameraName, ...
                'Color', cameraColor, ...
                'FontWeight', 'bold', ...
                'Tag', cameraTag);
            

            % store in app properties for later visibility checks
            if cameraID == 1
                app.cam1Position = posVector;
                app.cam1Rotation = rotMatrix;
                app.cam1Updated = datetime('now');
            else
                app.cam2Position = posVector;
                app.cam2Rotation = rotMatrix;
                app.cam2Updated = datetime('now');
            end
            
            drawnow limitrate;
        end

        function updateGraphs(app)
    

            % 1. Global Safety Check: Do we have enough data to plot?
        if isempty(app.timeHist) || size(app.posHist, 1) < 2
            return;
        end
        
        t = app.timeHist;
    
        % =========================================================
        % CASE A: Position/Velocity Panel is Visible
        % =========================================================
        if app.pnlPosTime.Visible == "on"
            
            % Extract Data
            posX = app.posHist(:, 1); posY = app.posHist(:, 2); posZ = app.posHist(:, 3);
            velX = app.velHist(:, 1); velY = app.velHist(:, 2); velZ = app.velHist(:, 3);
            
            % Update Position Lines
            set(app.linePosX, 'XData', t, 'YData', posX);
            set(app.linePosY, 'XData', t, 'YData', posY);
            set(app.linePosZ, 'XData', t, 'YData', posZ);
            
            % Update Velocity Lines
            set(app.lineVelX, 'XData', t, 'YData', velX);
            set(app.lineVelY, 'XData', t, 'YData', velY);
            set(app.lineVelZ, 'XData', t, 'YData', velZ);
            
            % Auto-scale X-Axes (Last 10 seconds)
            window = [max(0, t(end)-10), t(end)+0.5];
            xlim(app.XPosTime, window); xlim(app.YPosTime, window); xlim(app.ZPosTime, window);
            xlim(app.XVelTime, window); xlim(app.YVelTime, window); xlim(app.ZVelTime, window);
            
            % Auto-scale Y-Axes (Position)
            if ~isempty(posX)
                ylim(app.XPosTime, [min(posX)-0.2, max(posX)+0.2]);
                ylim(app.YPosTime, [min(posY)-0.2, max(posY)+0.2]);
                ylim(app.ZPosTime, [min(posZ)-0.2, max(posZ)+0.2]);
            end
            
            % Auto-scale Y-Axes (Velocity)
            if ~isempty(velX)
                ylim(app.XVelTime, [min(velX)-0.5, max(velX)+0.5]);
                ylim(app.YVelTime, [min(velY)-0.5, max(velY)+0.5]);
                ylim(app.ZVelTime, [min(velZ)-0.5, max(velZ)+0.5]);
            end
    
        % =========================================================
        % CASE B: Angular (Omega) Panel is Visible
        % =========================================================
        elseif app.pnlOmegaTime.Visible == "on"
            
            % Extract Data
            omegaX = app.omegaHist(:, 1);
            omegaY = app.omegaHist(:, 2);
            omegaZ = app.omegaHist(:, 3);
            
            % Update Angular Velocity Lines
            set(app.lineOmegaX, 'XData', t, 'YData', omegaX);
            set(app.lineOmegaY, 'XData', t, 'YData', omegaY);
            set(app.lineOmegaZ, 'XData', t, 'YData', omegaZ);
            
            % Auto-scale X-Axes (Last 10 seconds)
            window = [max(0, t(end)-10), t(end)+0.5];
            xlim(app.XOmegaTime, window); 
            xlim(app.YOmegaTime, window); 
            xlim(app.ZOmegaTime, window);
            
            % Auto-scale Y-Axes (Angular Velocity)
            if ~isempty(omegaX)
                ylim(app.XOmegaTime, [min(omegaX)-0.5, max(omegaX)+0.5]);
                ylim(app.YOmegaTime, [min(omegaY)-0.5, max(omegaY)+0.5]);
                ylim(app.ZOmegaTime, [min(omegaZ)-0.5, max(omegaZ)+0.5]);
            end
        end

            % Extract Data (Columns: 1=Yaw, 2=Pitch, 3=Roll)
            yaw   = app.orientationHist(:, 1);  % Z-axis rotation
            pitch = app.orientationHist(:, 2);  % Y-axis rotation
            roll  = app.orientationHist(:, 3);  % X-axis rotation
            
            % Update Lines
            % Assuming you have created lines like lineYaw, linePitch, lineRoll
            % If reusing velocity lines, change 'app.lineVelX' to whatever you named them
            set(app.lineOrientX, 'XData', t, 'YData', roll);  % Plot Roll on Top
            set(app.lineOrientY, 'XData', t, 'YData', pitch); % Plot Pitch in Middle
            set(app.lineOrientZ, 'XData', t, 'YData', yaw);   % Plot Yaw on Bottom
            
            % Auto-scale X-Axes (Last 10 seconds)
            window = [max(0, t(end)-10), t(end)+0.5];
            xlim(app.XOrientTime, window); 
            xlim(app.YOrientTime, window); 
            xlim(app.ZOrientTime, window);
        end
    
        function visible = canCameraSeePoint(~, camPosition, camRotation, targetPoint)
        %CANCAMERASEEPOINT Check if a camera can see a 3D point
        %
        % Inputs:
        %   camPosition: [x, y, z] camera position
        %   camRotation: 3×3 rotation matrix (world → camera)
        %   targetPoint: [x, y, z] point to check
        %
        % Output:
        %   visible: true if point is in camera's field of view
        
        % transform point to camera frame
        pointVector = targetPoint - camPosition;
        pointInCamFrame = camRotation' * pointVector';  % inverse rotation
        
        % check if point is in front of camera (+z)
        if pointInCamFrame(3) <= 0
            visible = false;
            return;
        end
        
        % check if point is within the fov bounds
        
        near = 0.2; %VERIFY
        far = 10; %VERIFY
        fovHorizontal = 0.9232;  
        fovVertical = 0.75602; 
        %this is just for test to give my cams super vision:
        %fovHorizontal = 10.0;  % Was 0.9232
        %fovVertical = 10.0;    % Was 0.75602
        
        % this is the distance to the point
        distance = pointInCamFrame(3);
        
        % check if its within the distance range
        if distance < near || distance > far
            visible = false;
            return;
        end
        
        % angular positions
        angleHorizontal = atan2(pointInCamFrame(1), pointInCamFrame(3));
        angleVertical = atan2(pointInCamFrame(2), pointInCamFrame(3));
        
        % check if withing fov
        if abs(angleHorizontal) > fovHorizontal/2 || abs(angleVertical) > fovVertical/2
            visible = false;
            return;
        end
        
        visible = true;
    end
        
        function updateDroneMarkers(app, dronePosition, droneRotation)
            %for this to work fix the rotation and the matrices for the
            %orientations
    
            d = 0.15; %check this distance from centroid
    
            localMarkers = [
             d,  d, 0;   % Front-right (Yellow)
            -d,  d, 0;   % Front-left (Blue)
            -d, -d, 0;   % Back-left (Green)
             d, -d, 0;   % Back-right (Red)
            ];
    
            app.droneMarkers = zeros(4, 3);
            for i = 1:4
                worldPos = droneRotation * localMarkers(i, :)' + dronePosition';
                app.droneMarkers(i, :) = worldPos';
            end
                
          end
        
        function checkDroneVisibility(app)
              if isempty(app.droneMarkers)
                return;  
            end
            
            % Initialize dynamic visibility matrix: N cameras × 4 markers
            visibility = false(app.numCameras, 4);
            
            % Loop through all dynamic cameras
            for k = 1:app.numCameras
                if k <= length(app.CameraParams) && ~isempty(app.CameraParams{k})
                    c = app.CameraParams{k};
                    
                    % Parse Position & Rotation
                    camPos = [str2double(c.PositionX), str2double(c.PositionY), str2double(c.PositionZ)];
                    R_flat = [str2double(c.Orientation0), str2double(c.Orientation1), str2double(c.Orientation2), ...
                              str2double(c.Orientation3), str2double(c.Orientation4), str2double(c.Orientation5), ...
                              str2double(c.Orientation6), str2double(c.Orientation7), str2double(c.Orientation8)];
                    R_fix = [0 0 1; 0 1 0; -1 0 0];
                    camRot = reshape(R_flat, 3, 3)' * R_fix;
                    
                    % Check visibility for all 4 markers
                    for i = 1:4
                        visibility(k, i) = app.canCameraSeePoint(camPos, camRot, app.droneMarkers(i, :));
                    end
                end
            end
            
            % Update UI Text (Dynamically build the string)
            displayText = "=== Drone Marker Visibility ===" + newline + ...
                          "         Yellow  Blue  Green  Red" + newline;
            for k = 1:app.numCameras
                displayText = displayText + sprintf("Cam %d:    %d      %d     %d     %d\n", ...
                              k, visibility(k,1), visibility(k,2), visibility(k,3), visibility(k,4));
            end
            app.SeenTextArea.Value = cellstr(displayText);
            
            % Draw the dynamic lines
            app.drawVisibilityLines(visibility);
            
        end
        
        function drawVisibilityLines(app, visibility)
            % 1. Clean up old lines
            delete(findobj(app.ThreeD, 'Tag', 'VisibilityLine'));
            
            if isempty(app.droneMarkers)
                return;
            end
            
            % 2. Rock-Solid RGB Colors (Cyan, Magenta, Green, Yellow, Black, Red)
            rgbColors = [0 1 1;  1 0 1;  0 1 0;  1 1 0;  0 0 0;  1 0 0];
            
            % 3. Draw New Lines
            for k = 1:app.numCameras
                if k <= length(app.CameraParams) && ~isempty(app.CameraParams{k})
                    c = app.CameraParams{k};
                    camPos = [str2double(c.PositionX), str2double(c.PositionY), str2double(c.PositionZ)];
                    
                    % Pick an RGB color safely based on camera ID
                    cIdx = mod(k-1, size(rgbColors, 1)) + 1;
                    lineColor = rgbColors(cIdx, :);
                    
                    for i = 1:4
                        if visibility(k, i)
                            % Explicit syntax to prevent silent crashes
                            plot3(app.ThreeD, ...
                                [camPos(1), app.droneMarkers(i,1)], ...
                                [camPos(2), app.droneMarkers(i,2)], ...
                                [camPos(3), app.droneMarkers(i,3)], ...
                                'Color', lineColor, ...
                                'LineStyle', '-', ...
                                'LineWidth', 2.5, ...
                                'Tag', 'VisibilityLine');
                        end
                    end
                end
            end
        end
        
        function camStruct = buildCameraJSONStruct(~, cp, cameraID)

            R = cp.RotationMatrices(:,:,1);
            t = cp.TranslationVectors(1,:);
            Rflat = reshape(R.', 1, 9);   % row-major
            
            camStruct = struct( ...
                "CameraID", string(cameraID), ...
                "ImagerIndex", "00", ...
                "LensCenterX", string(cp.PrincipalPoint(1)), ...
                "LensCenterY", string(cp.PrincipalPoint(2)), ...
                "HorizontalFocalLength", string(cp.FocalLength(1)), ...
                "VerticalFocalLength", string(cp.FocalLength(2)), ...
                "KC1", string(cp.RadialDistortion(1)), ...
                "KC2", string(cp.RadialDistortion(2)), ...
                "KC3", string(cp.RadialDistortion(3)), ...
                "Tangential0", string(cp.TangentialDistortion(1)), ...
                "Tangential1", string(cp.TangentialDistortion(2)), ...
                "ImagerPixelWidth", string(cp.ImageSize(2)), ...
                "ImagerPixelHeight", string(cp.ImageSize(1)), ...
                "RinglightType", "2", ...
                "RinglightWavelength", "-1", ...
                "FilterSwitchType", "1", ...
                "PositionX", string(t(1)), ...
                "PositionY", string(t(2)), ...
                "PositionZ", string(t(3)), ...
                "Orientation0", string(Rflat(1)), ...
                "Orientation1", string(Rflat(2)), ...
                "Orientation2", string(Rflat(3)), ...
                "Orientation3", string(Rflat(4)), ...
                "Orientation4", string(Rflat(5)), ...
                "Orientation5", string(Rflat(6)), ...
                "Orientation6", string(Rflat(7)), ...
                "Orientation7", string(Rflat(8)), ...
                "Orientation8", string(Rflat(9)), ...
                "IntrinsicModelError", string(cp.MeanReprojectionError) ...
            );
            end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numCameras = str2double(inputdlg("Enter number of cameras in setup", "Camera number", 1, {'1'}));
            app.logData = "";
            app.CameraParams = cell(1, app.numCameras);
            app.SquareSizeMM = 20; %change this for the calibration square - mine is 41mm
            %this just puts some 3D axes upon startup of the app so it
            %doesnt look barren

            % Populate the drop down menu with the amount of cameras and
            % different calibration properties
            dropText = ["Camera Extrinsics", "Ground Plane"];

            for k = 1:app.numCameras
                dropText(end+1) = "Camera " + num2str(k) + " Intrinsics";
            end

           app.DropDown.Items = dropText;

            hold(app.ThreeD, 'on');
            axis(app.ThreeD, 'equal');
            grid(app.ThreeD, 'on');
            view(app.ThreeD, 3);
            xlabel(app.ThreeD, 'X (m)'); 
            ylabel(app.ThreeD, 'Y (m)'); 
            zlabel(app.ThreeD, 'Z (m)');

            % 2. DRAW THE GROUND PLANE (Y = 0)
            % Draws a 10x10 meter grey floor centered at (0,0)
            patch(app.ThreeD, ...
                [-5, 5, 5, -5], ...       % X Coordinates
                [0, 0, 0, 0], ...         % Y Coordinates (Flat at 0)
                [-5, -5, 5, 5], ...       % Z Coordinates
                [0.8 0.8 0.8], ...        % Color (Light Grey)
                'FaceAlpha', 0.3, ...     % Transparency
                'EdgeColor', [0.5 0.5 0.5]); 
            
            % Add a label for the ground
            text(app.ThreeD, -6, 0, -6, 'Ground Plane', 'Color', 'k', 'VerticalAlignment', 'bottom');

            %this is to get the accurate time
            app.lastTime = tic;
            app.startTime = datetime('now'); % For X-axis time
            
        end

        % Button pushed function: CameraPreviewButton
        function CameraPreviewButtonPushed(app, event)
            % Check if we actually have calibration data loaded
            if isempty(app.CameraParams)
                uialert(app.UIFigure, "No calibration data found. Please 'Load Calibration' or run a new one first.", "Missing Data", 'Icon', 'warning');
                return;
            end
            
            % Switch to the 3D View Panel automatically
            app.pnlCalibrate.Visible = "off";
            app.pnlTracking.Visible = "off";
            app.pnlSeen.Visible = "on";
            
            % Prepare the 3D Axes
            cla(app.ThreeD);
            hold(app.ThreeD, 'on');
            grid(app.ThreeD, 'on');
            axis(app.ThreeD, 'equal');
            view(app.ThreeD, 3);
            xlabel(app.ThreeD, 'X (m)'); ylabel(app.ThreeD, 'Y (m)'); zlabel(app.ThreeD, 'Z (m)');
            
            % Draw the Ground Plane
            patch(app.ThreeD, [-5, 5, 5, -5], [0, 0, 0, 0], [-5, -5, 5, 5], ...
                 [0.8 0.8 0.8], 'FaceAlpha', 0.3, 'EdgeColor', [0.5 0.5 0.5]); 
            
            camerasPlotted = 0;
            
            % Loop through and draw all static cameras
            for i = 1:app.numCameras
                if i <= length(app.CameraParams) && ~isempty(app.CameraParams{i})
                    c = app.CameraParams{i};
                    
                    % Extract Position (Convert mm to meters)
                    pos = [str2double(c.PositionX), str2double(c.PositionY), str2double(c.PositionZ)];
                    
                    % Extract Rotation
                    R_flat = [str2double(c.Orientation0), str2double(c.Orientation1), str2double(c.Orientation2), ...
                              str2double(c.Orientation3), str2double(c.Orientation4), str2double(c.Orientation5), ...
                              str2double(c.Orientation6), str2double(c.Orientation7), str2double(c.Orientation8)];
                    rot = reshape(R_flat, 3, 3)'; 
                    
                    % Plot Frustum
                    R_fix = [0 0 1; 0 1 0; -1 0 0];
                    app.makeFrustum(pos, rot * R_fix, "StaticCam_"+i);
                    text(app.ThreeD, pos(1), pos(2), pos(3) + 0.2, "Cam " + i, 'Color', 'c', 'FontWeight', 'bold');
                    
                    camerasPlotted = camerasPlotted + 1;
                end
            end
            
            title(app.ThreeD, 'Static Camera Preview');
            
            % Log it
            app.logData = app.logData + newline + "Previewing " + camerasPlotted + " cameras in 3D.";
            app.TextArea.Value = app.logData;
        end

        % Button pushed function: btnThreeDView
        function btnThreeDViewButtonPushed(app, event)
            app.pnlCalibrate.Visible = "off";
            app.pnlTracking.Visible = "off";
            app.pnlSeen.Visible = "on";
        end

        % Button pushed function: btnPathway
        function btnPathwayButtonPushed(app, event)

            app.pnlViewing.Visible = "on";
            app.pnlPathway.Visible = "off";   % (If this is a separate panel, keep off. Use Viewing for 3D)
            app.pnlPosTime.Visible = "off";
            app.pnlOmegaTime.Visible = "off";
        end

        % Button pushed function: btnPosGraphs
        function btnPosGraphsButtonPushed(app, event)
                % Switch to position graphs panel
                app.pnlViewing.Visible = "off";
                app.pnlPathway.Visible = "off";
                app.pnlPosTime.Visible = "on";
                app.pnlOmegaTime.Visible = "off";
                
                % If tracking is active, initialize live plots
                if app.isTracking
                    % Setup axes for live plotting
                    cla(app.XPosTime); hold(app.XPosTime, 'on');
                    cla(app.YPosTime); hold(app.YPosTime, 'on');
                    cla(app.ZPosTime); hold(app.ZPosTime, 'on');
                    cla(app.XVelTime); hold(app.XVelTime, 'on');
                    cla(app.YVelTime); hold(app.YVelTime, 'on');
                    cla(app.ZVelTime); hold(app.ZVelTime, 'on');
                    
                    % Create persistent line objects for fast updating
                    app.linePosX = plot(app.XPosTime, NaN, NaN, 'r-', 'LineWidth', 2);
                    app.linePosY = plot(app.YPosTime, NaN, NaN, 'g-', 'LineWidth', 2);
                    app.linePosZ = plot(app.ZPosTime, NaN, NaN, 'b-', 'LineWidth', 2);
                    
                    app.lineVelX = plot(app.XVelTime, NaN, NaN, 'r-', 'LineWidth', 2);
                    app.lineVelY = plot(app.YVelTime, NaN, NaN, 'g-', 'LineWidth', 2);
                    app.lineVelZ = plot(app.ZVelTime, NaN, NaN, 'b-', 'LineWidth', 2);
                    
                    % Labels
                    xlabel(app.XPosTime, 'Time (s)', 'Color', [0 0 1]);
                    ylabel(app.XPosTime, 'X Position (m)', 'Color', [0 0 1]);
                    title(app.XPosTime, 'X Position vs Time', 'Color', [0 0 1]);
                    grid(app.XPosTime, 'on');
                    
                    xlabel(app.YPosTime, 'Time (s)', 'Color', [0 0 1]);
                    ylabel(app.YPosTime, 'Y Position (m)', 'Color', [0 0 1]);
                    title(app.YPosTime, 'Y Position vs Time', 'Color', [0 0 1]);
                    grid(app.YPosTime, 'on');
                    
                    xlabel(app.ZPosTime, 'Time (s)', 'Color', [0 0 1]);
                    ylabel(app.ZPosTime, 'Z Position (m)', 'Color', [0 0 1]);
                    title(app.ZPosTime, 'Z Position vs Time', 'Color', [0 0 1]);
                    grid(app.ZPosTime, 'on');
                    
                    xlabel(app.XVelTime, 'Time (s)', 'Color', [0 0 1]);
                    ylabel(app.XVelTime, 'X Velocity (m/s)', 'Color', [0 0 1]);
                    title(app.XVelTime, 'X Velocity vs Time', 'Color', [0 0 1]);
                    grid(app.XVelTime, 'on');
                    
                    xlabel(app.YVelTime, 'Time (s)', 'Color', [0 0 1]);
                    ylabel(app.YVelTime, 'Y Velocity (m/s)', 'Color', [0 0 1]);
                    title(app.YVelTime, 'Y Velocity vs Time', 'Color', [0 0 1]);
                    grid(app.YVelTime, 'on');
                    
                    xlabel(app.ZVelTime, 'Time (s)', 'Color', [0 0 1]);
                    ylabel(app.ZVelTime, 'Z Velocity (m/s)', 'Color', [0 0 1]);
                    title(app.ZVelTime, 'Z Velocity vs Time', 'Color', [0 0 1]);
                    grid(app.ZVelTime, 'on');
                    
                    app.logData = app.logData + newline + "Switched to live position/velocity graphs";
                    app.TextArea.Value = app.logData;
                    scroll(app.TextArea, 'bottom');
                    
                    % Force immediate update
                    updateGraphs(app);
                else
                    % Not tracking - show message
                    title(app.XPosTime, 'Start tracking to see live data');
                end

        end

        % Button pushed function: btnAngularVGraphs
        function btnAngularVGraphsButtonPushed(app, event)
        %to get to the orientation graphs remember to change in the UDP
        %function and the updateGraphs function!!!!

             app.pnlViewing.Visible = "off";
             app.pnlPathway.Visible = "off";
             app.pnlPosTime.Visible = "off";
             app.pnlOmegaTime.Visible = "on";
        if app.isTracking
                % Setup axes for live plotting
                cla(app.XOmegaTime); hold(app.XOmegaTime, 'on');
                cla(app.YOmegaTime); hold(app.YOmegaTime, 'on');
                cla(app.ZOmegaTime); hold(app.ZOmegaTime, 'on');

                
                % Create persistent line objects for fast updating
                app.lineOmegaX = plot(app.XOmegaTime, NaN, NaN, 'r-', 'LineWidth', 2);
                app.lineOmegaY = plot(app.YOmegaTime, NaN, NaN, 'g-', 'LineWidth', 2);
                app.lineOmegaZ = plot(app.ZOmegaTime, NaN, NaN, 'b-', 'LineWidth', 2);
                
                
                % Labels
                xlabel(app.XOmegaTime, 'Time (s)', 'Color', [0 0 1]);
                ylabel(app.XOmegaTime, 'ω x (rad/s)', 'Color', [0 0 1]);
                title(app.XOmegaTime, 'ω x vs Time', 'Color', [0 0 1]);
                grid(app.XOmegaTime, 'on');
                
                xlabel(app.YOmegaTime, 'Time (s)', 'Color', [0 0 1]);
                ylabel(app.YOmegaTime, 'ω y (rad/s)', 'Color', [0 0 1]);
                title(app.YOmegaTime, 'ω y vs Time', 'Color', [0 0 1]);
                grid(app.YOmegaTime, 'on');
                
                xlabel(app.ZOmegaTime, 'Time (s)', 'Color', [0 0 1]);
                ylabel(app.ZOmegaTime, 'ω z (rad/s)', 'Color', [0 0 1]);
                title(app.ZOmegaTime, 'ω z vs Time', 'Color', [0 0 1]);
                grid(app.ZOmegaTime, 'on');
                
                
                cla(app.XOrientTime); hold(app.XOrientTime, 'on');
                cla(app.YOrientTime); hold(app.YOrientTime, 'on');
                cla(app.ZOrientTime); hold(app.ZOrientTime, 'on');
                
                % 3. Create persistent line objects (Stored in properties)
                % Note: You must have added lineOrientX/Y/Z to properties first!
                app.lineOrientX = plot(app.XOrientTime, NaN, NaN, 'r-', 'LineWidth', 2);
                app.lineOrientY = plot(app.YOrientTime, NaN, NaN, 'g-', 'LineWidth', 2);
                app.lineOrientZ = plot(app.ZOrientTime, NaN, NaN, 'b-', 'LineWidth', 2);
                
                % 4. Labels (Roll, Pitch, Yaw)
                % Roll (X)
                xlabel(app.XOrientTime, 'Time (s)', 'Color', [0 0 1]);
                ylabel(app.XOrientTime, 'Roll (rad)', 'Color', [0 0 1]);
                title(app.XOrientTime, 'Roll vs Time', 'Color', [0 0 1]);
                grid(app.XOrientTime, 'on');
                
                % Pitch (Y)
                xlabel(app.YOrientTime, 'Time (s)', 'Color', [0 0 1]);
                ylabel(app.YOrientTime, 'Pitch (rad)', 'Color', [0 0 1]);
                title(app.YOrientTime, 'Pitch vs Time', 'Color', [0 0 1]);
                grid(app.YOrientTime, 'on');
                
                % Yaw (Z)
                xlabel(app.ZOrientTime, 'Time (s)', 'Color', [0 0 1]);
                ylabel(app.ZOrientTime, 'Yaw (rad)', 'Color', [0 0 1]);
                title(app.ZOrientTime, 'Yaw vs Time', 'Color', [0 0 1]);
                grid(app.ZOrientTime, 'on');
                
                app.logData = app.logData + newline + "Switched to live orientation/ω graphs";
                app.TextArea.Value = app.logData;
                scroll(app.TextArea, 'bottom');
                
                % Force immediate update
                updateGraphs(app);
            else
                % Not tracking - show message
                title(app.XOmegaTime, 'Start tracking to see live data');
        end

           
        end

        % Button pushed function: btnCalibrate
        function btnCalibrateButtonPushed(app, event)
            app.pnlCalibrate.Visible = "on";
            app.pnlTracking.Visible = "off";
            app.pnlSeen.Visible = "off";
        end

        % Button pushed function: btnTracking
        function btnTrackingButtonPushed(app, event)
            app.pnlCalibrate.Visible = "off";
            app.pnlTracking.Visible = "on";
            app.pnlSeen.Visible = "off";
        end

        % Button pushed function: GetCalibrationDataButton
        function GetCalibrationDataButtonPushed(app, event)
            
            % Settings
            numCams    = app.numCameras;      
            squareSize = app.SquareSizeMM;     

            % Check which claibration mode to use
            mode = app.DropDown.Value;

            switch mode
                case "Camera Extrinsics"
                    if app.isGroundSet == true
                        uialert(app.UIFigure, "Please set Ground Plane first as a reference is needed!", ...
                                "Missing reference point");
                        return;
                    end

                    folderPath = uigetdir(pwd, "Select folder with extrinsic calibration images");

                    if folderPath == 0
                        app.logData = app.logData + newline + "Operation cancelled.";
                        app.TextArea.Value = app.logData;
                        drawnow;
                    end

                    % Group images by their poses
                    % NOTE it is important for th enaming convension to be
                    % pose_--_cam_--.bmp for example pose_01_cam_02
                    for j = 1:length(folderPath)
                        name = files(j).name;

                        tokens = regexp(name, 'pose_(\d+)_cam_(\d+)', 'tokens');
                    
                        if isempty(tokens)
                            error('Invalid filename: %s', name);
                        end
                    
                        poseIdx = str2double(tokens{1}{1});
                        camIdx  = str2double(tokens{1}{2});
                    
                        % if number of camera pictures does not match numCams
                        if camIdx > numCams
                            error('Camera index exceeds expected number of cameras.');
                        end
                    
                        maxPose = max(maxPose, poseIdx);
                    
                        key = sprintf('%d_%d', poseIdx, camIdx);
                    
                        imageData(key) = imread(fullfile(folderPath, name));

                        % Make an array of poses and cams
                        ExtrinsicImages = cell(maxPose, numCams);

                        for pose = 1:maxPose
                            for cam = 1:numCams
                        
                                key = sprintf('%d_%d', pose, cam);
                        
                                if ~isKey(imageData, key)
                                    error('Missing image for Pose %d Camera %d', pose, cam);
                                end
                        
                                ExtrinsicImages{pose, cam} = imageData(key);
                        
                            end
                        end


                    end


                case "Ground Plane"

                otherwise
                    % Get camera number
                    camNumber = sscanf(app.DropDown.Value, "%*s %d %*s");

                    app.logData = app.logData + newline+ "Beginning calibration for Camera " + num2str(camNumber);
                    app.TextArea.Value = app.logData;
                    drawnow;

                    % Prompt user for folder
                    folderPath = uigetdir(pwd, "Select folder containing images");

                    if folderPath == 0
                        app.logData = app.logData + newline+ "Operation cancelled.";
                        app.TextArea.Value = app.logData;
                        drawnow;
                    end
                
                    % Load images in
                    imageFiles = [dir(fullfile(folderPath,'*.jpg'));
                                  dir(fullfile(folderPath,'*.png'));
                                  dir(fullfile(folderPath,'*.bmp'))];
                
                    if isempty(imageFiles)
                        uialert(app.UIFigure, 'No calibration images found.', 'Error');
                        return;
                    end
                
                    imageFileNames = fullfile(folderPath, {imageFiles.name});
                
                    % Perform calibration
                    [imagePoints, boardSize, imagesUsed] = ...
                        detectCheckerboardPoints(imageFileNames);

                    worldPoints = generateCheckerboardPoints(boardSize, squareSize);
                
                    % Generate image size
                    I = imread(imageFileNames{find(imagesUsed,1)});
                    imageSize = [size(I,1), size(I,2)];
                
                    % Estimate camera parameters
                    cameraParams = estimateCameraParameters( ...
                                    imagePoints, worldPoints, ...
                                    'ImageSize', imageSize, ...
                                    'EstimateSkew', false, ...
                                    'EstimateTangentialDistortion', true, ...
                                    'NumRadialDistortionCoefficients', 3);

                
                    % Create struct by calling function
                    camStruct = app.buildCameraJSONStruct(cameraParams, camNumber);
                
                    app.CameraParams{camNumber} = camStruct;
                    

                    %this is the code that will be used to 
                    x = str2double(camStruct.PositionX)/100;
                    y = str2double(camStruct.PositionY)/100;
                    z = str2double(camStruct.PositionZ)/100;
                    
                    pos_vector = [x, y, z];

                    % Rebuild the Rotation Matrix (9 numbers -> 3x3 Matrix)
                    R_flat = [str2double(camStruct.Orientation0), str2double(camStruct.Orientation1), str2double(camStruct.Orientation2), ...
                              str2double(camStruct.Orientation3), str2double(camStruct.Orientation4), str2double(camStruct.Orientation5), ...
                              str2double(camStruct.Orientation6), str2double(camStruct.Orientation7), str2double(camStruct.Orientation8)];
                    
                    rotMatrix = reshape(R_flat, 3, 3)'; % Transpose to fix ordering
                    
                    % 2. FIX THE VISUALS (Standard Camera Fix)
                    % Real cameras define "Forward" as Z. MATLAB plotting needs "Forward" as X.
                    % This matrix rotates the plot 90 degrees so it looks right.
                    R_fix = [0 0 1; 0 1 0; -1 0 0]; 
                    finalRot = rotMatrix * R_fix;
                    
                    % 3. PLOT
                    app.makeFrustum(pos_vector, finalRot, "Cam");
                    
                    % Add a text label so you know which one it is
                    text(app.ThreeD, x, y, z + 0.2, "Cam " + camNumber, 'Color', 'c');
                
                    app.logData = app.logData + newline+ "Calibration successful.";
                    app.TextArea.Value = app.logData;
                    drawnow;

                    % Update labels
                    currentDate = datetime('now');
                    dateString = string(currentDate);
                    app.LastCalibrationLabel.Text = "Last Calibration: " + dateString;
                    
                    camStruct = app.CameraParams{camNumber};
                    app.RatingLabel.Text = "Rating " + num2str(camStruct.IntrinsicModelError, 3) + " px";
            end

            app.SaveCalibrationButton.Enable = "on";


        end

        % Button pushed function: StartTrackingButton
        function StartTrackingButtonPushed(app, event)

          
            modelName = 'AynchronousEKF_SimpleCircleTrajectory';
            
            % 1. Check Simulink
            if ~bdIsLoaded(modelName)
                uialert(app.UIFigure, "Model not loaded.", 'Error');
                return;
            end
            if ~strcmp(get_param(modelName, 'SimulationStatus'), 'running')
                uialert(app.UIFigure, "Start Simulink first!", 'Error');
                return;
            end
            
            % 2. Setup UDP
            try
                if ~isempty(app.udpReceiver) && isvalid(app.udpReceiver)
                    delete(app.udpReceiver);
                end
                
                app.udpReceiver = udpport("datagram", "LocalPort", 25000);
                configureCallback(app.udpReceiver, "datagram", 1, @(src,evt)updateDroneFromUDP(app,src,evt));
                
            catch ME
                uialert(app.UIFigure, 'UDP Error: ' + ME.message, 'Error');
                return;
            end
            
            % 3. Setup 3D View
            cla(app.ThreeD);
            hold(app.ThreeD, 'on');
            grid(app.ThreeD, 'on');
            axis(app.ThreeD, 'equal');
            view(app.ThreeD, 3);
            app.ThreeD.Clipping = "off";
            
            xlabel(app.ThreeD, 'X (m)');
            ylabel(app.ThreeD, 'Y (m)');
            zlabel(app.ThreeD, 'Z (m)');
            
            xlim(app.ThreeD, [-2, 5]);
            ylim(app.ThreeD, [-2, 2]);
            zlim(app.ThreeD, [-1, 2]);
            
            app.drone = app.createDroneObject();
            app.show3D(app.drone);
            app.trail = animatedline(app.ThreeD, 'Color', 'b', 'LineWidth', 2, 'MaximumNumPoints', 1000);
            
            title(app.ThreeD, 'Live Tracking');
            
            app.SightLines = gobjects(0); % Reset sight lines
            
            for i = 1:app.numCameras
                if i <= length(app.CameraParams) && ~isempty(app.CameraParams{i})
                    c = app.CameraParams{i};
                    
                    % scale these positions if using different
                    % measurements!!!!
                    pos = [str2double(c.PositionX), str2double(c.PositionY), str2double(c.PositionZ)];
                    
                    % Extract and rebuild rotation
                    R_flat = [str2double(c.Orientation0), str2double(c.Orientation1), str2double(c.Orientation2), ...
                              str2double(c.Orientation3), str2double(c.Orientation4), str2double(c.Orientation5), ...
                              str2double(c.Orientation6), str2double(c.Orientation7), str2double(c.Orientation8)];
                    rot = reshape(R_flat, 3, 3)'; 
                    
                    % Plot Frustum
                    R_fix = [0 0 1; 0 1 0; -1 0 0];
                    app.makeFrustum(pos, rot * R_fix, "StaticCam_"+i);
                    text(app.ThreeD, pos(1), pos(2), pos(3) + 0.2, "Cam " + i, 'Color', 'c');
                end
            end
            % ========================================================
            
            title(app.ThreeD, 'Live Tracking');

            % 4. Initialize Data Buffers
            app.timeHist = [];
            app.posHist = [];
            app.velHist = [];
            app.omegaHist = [];
            app.orientationHist = [];
            app.maxHistLength = 5000;  % Keep last 5000 points (~50 seconds at 100Hz) CHANGE FOR faster speed
            app.startTime = tic;
            
            % 5. Setup Graph Lines (Persistent for fast updating)
            if app.pnlPosTime.Visible == "on"
                % Initialize live plot lines
                cla(app.XPosTime); hold(app.XPosTime, 'on');
                cla(app.YPosTime); hold(app.YPosTime, 'on');
                cla(app.ZPosTime); hold(app.ZPosTime, 'on');

                cla(app.XVelTime); hold(app.XVelTime, 'on');
                cla(app.YVelTime); hold(app.YVelTime, 'on');
                cla(app.ZVelTime); hold(app.ZVelTime, 'on');
                
                cla(app.XOmegaTime); hold(app.XOmegaTime, 'on');
                cla(app.YOmegaTime); hold(app.YOmegaTime, 'on');
                cla(app.ZOmegaTime); hold(app.ZOmegaTime, 'on');

                app.linePosX = plot(app.XPosTime, NaN, NaN, 'r-', 'LineWidth', 2);
                app.linePosY = plot(app.YPosTime, NaN, NaN, 'g-', 'LineWidth', 2);
                app.linePosZ = plot(app.ZPosTime, NaN, NaN, 'b-', 'LineWidth', 2);
                
                app.lineVelX = plot(app.XVelTime, NaN, NaN, 'r-', 'LineWidth', 2);
                app.lineVelY = plot(app.YVelTime, NaN, NaN, 'g-', 'LineWidth', 2);
                app.lineVelZ = plot(app.ZVelTime, NaN, NaN, 'b-', 'LineWidth', 2);
                
                app.lineOmegaX = plot(app.XOmegaTime, NaN, NaN, 'r-', 'LineWidth', 2);
                app.lineOmegaY = plot(app.YOmegaTime, NaN, NaN, 'g-', 'LineWidth', 2);
                app.lineOmegaZ = plot(app.ZOmegaTime, NaN, NaN, 'b-', 'LineWidth', 2);
                
                
                % Labels
                xlabel(app.XPosTime, 'Time (s)'); ylabel(app.XPosTime, 'X (m)'); title(app.XPosTime, 'X Position');
                xlabel(app.YPosTime, 'Time (s)'); ylabel(app.YPosTime, 'Y (m)'); title(app.YPosTime, 'Y Position');
                xlabel(app.ZPosTime, 'Time (s)'); ylabel(app.ZPosTime, 'Z (m)'); title(app.ZPosTime, 'Z Position');

                xlabel(app.XVelTime, 'Time (s)'); ylabel(app.XVelTime, 'Vx (m/s)'); title(app.XVelTime, 'X Velocity');
                xlabel(app.YVelTime, 'Time (s)'); ylabel(app.YVelTime, 'Vy (m/s)'); title(app.YVelTime, 'Y Velocity');
                xlabel(app.ZVelTime, 'Time (s)'); ylabel(app.ZVelTime, 'Vz (m/s)'); title(app.ZVelTime, 'Z Velocity');
                
                xlabel(app.XOmegaTime, 'Time (s)'); ylabel(app.XOmegaTime, 'ωx (rad/s)'); title(app.XVelTime, 'X Angular Velocity');
                xlabel(app.YOmegaTime, 'Time (s)'); ylabel(app.YOmegaTime, 'ωy (rad/s)'); title(app.YVelTime, 'Y Angular Velocity');
                xlabel(app.ZOmegaTime, 'Time (s)'); ylabel(app.ZOmegaTime, 'ωz (rad/s)'); title(app.ZVelTime, 'Z Angular Velocity');

                grid(app.XPosTime, 'on'); grid(app.YPosTime, 'on'); grid(app.ZPosTime, 'on');
                grid(app.XVelTime, 'on'); grid(app.YVelTime, 'on'); grid(app.ZVelTime, 'on');
                grid(app.XOmegaTime, 'on'); grid(app.YOmegaTime, 'on'); grid(app.ZOmegaTime, 'on');
            end
            
            % 6. Start Graph Update Timer (10 Hz - graphs don't need 100 Hz)
            if ~isempty(app.graphTimer) && isvalid(app.graphTimer)
                stop(app.graphTimer);
                delete(app.graphTimer);
            end
            
            app.graphTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.1, ...  % Update graphs every 100ms (10 Hz)
                'BusyMode', 'drop', ...
                'TimerFcn', @(~,~)updateGraphs(app));
            start(app.graphTimer);
            
            % 7. UI State
            app.isTracking = true;
            app.StartTrackingButton.Enable = 'off';
            app.StopTrackingButton.Enable = 'on';
            
            app.logData = app.logData + newline + "Live tracking started";
            app.TextArea.Value = app.logData;
            scroll(app.TextArea, 'bottom');
        end

        % Button pushed function: StopTrackingButton
        function StopTrackingButtonPushed(app, event)
            app.isTracking = false;
    
            % Stop graph timer
            if ~isempty(app.graphTimer) && isvalid(app.graphTimer)
                stop(app.graphTimer);
                delete(app.graphTimer);
                app.graphTimer = [];
            end
            
            % Stop UDP
            if ~isempty(app.udpReceiver) && isvalid(app.udpReceiver)
                try
                    configureCallback(app.udpReceiver, "off");
                    flush(app.udpReceiver);
                    delete(app.udpReceiver);
                    app.udpReceiver = [];
                catch
                end
            end
            
            app.StartTrackingButton.Enable = 'on';
            app.StopTrackingButton.Enable = 'off';
            title(app.ThreeD, 'Tracking Stopped');
            
            app.logData = app.logData + newline + "Tracking stopped";
            app.TextArea.Value = app.logData;
            scroll(app.TextArea, 'bottom');
        end

        % Button pushed function: SaveCalibrationButton
        function SaveCalibrationButtonPushed(app, event)
            % Dialogue box to prompt json name
            [fileName, filePath] = uiputfile('*.json', 'Save camera parameters as');

            if isequal(fileName, 0)
                % User clicked Cancel
                return;
            end
            
            fullFileName = fullfile(filePath, fileName);
            
            % Encode as JSON
            jsonText = jsonencode(app.CameraParams, 'PrettyPrint', true);
            
            % Save JSON
            fid = fopen(fullFileName, 'w');
            fwrite(fid, jsonText, 'char');
            fclose(fid);

            app.logData = app.logData + newline + "Calibration saved!";
            app.TextArea.Value = app.logData;

        end

        % Button pushed function: LoadCalibrationButton
        function LoadCalibrationButtonPushed(app, event)
            % % Dialogue box to select json file
            % [fileName, filePath] = uigetfile('*.json', 'Load camera parameters');
            % 
            % if isequal(fileName, 0)
            %     % User clicked Cancel
            %     return;
            % end
            % 
            % fullFileName = fullfile(filePath, fileName);
            % 
            % % Open file
            % fid = fopen(fullFileName, 'r');
            % raw = fread(fid, inf, 'char');
            % fclose(fid);
            % 
            % % Convert to text
            % jsonText = char(raw');
            % 
            % % Decode JSON
            % loadedParams = jsondecode(jsonText);
            % 
            % % Store into app property
            % app.CameraParams = loadedParams;
            % 
            % % % Log update
            % % app.logData = app.logData + newline + "Calibration loaded!";
            % % app.TextArea.Value = app.logData;
            % % drawnow;
            % 
            % % Display nicely in TextArea
            % displayText = jsonencode(app.CameraParams, 'PrettyPrint', true);
            % app.TextArea.Value = splitlines(string(displayText));
            % 
            % % Enable save button
            % app.SaveCalibrationButton.Enable = "on";

            % Dialogue box to select json file
            [fileName, filePath] = uigetfile('*.json', 'Load camera parameters');
        
            if isequal(fileName, 0)
                % User clicked Cancel
                return;
            end
        
            fullFileName = fullfile(filePath, fileName);
        
            % Open file and read raw text
            fid = fopen(fullFileName, 'r');
            raw = fread(fid, inf, '*char')';
            fclose(fid);
        
            % 1. CLEAN THE TEXT (Fixes the JSON BOM Error)
            raw = strrep(raw, char(65279), ''); % Remove hidden BOM character
            raw = strrep(raw, char(160), ' ');  % Replace non-breaking spaces
        
            % 2. DECODE JSON
            try
                loadedParams = jsondecode(raw);
            catch ME
                uialert(app.UIFigure, "JSON Format Error: " + ME.message, "Decode Failed");
                return;
            end
        
            % 3. REFORMAT TO APP STRUCTURE
            % The JSON is a struct (loadedParams.Camera_5, etc.)
            % We must map it into the cell array: app.CameraParams{5}
            app.CameraParams = cell(1, app.numCameras);
            
            % Get all field names (e.g., 'Camera_1', 'Camera_5')
            fields = fieldnames(loadedParams);
            
            for i = 1:length(fields)
                camData = loadedParams.(fields{i});
                
                % Check the CameraID number inside the data
                camIDNum = round(str2double(camData.CameraID));
                
                % Slot it into the array if it fits within numCameras
                if camIDNum > 0 && camIDNum <= app.numCameras
                    app.CameraParams{camIDNum} = camData;
                end
            end
        
            % 4. UPDATE UI
            % Display nicely in TextArea
            displayText = jsonencode(app.CameraParams, 'PrettyPrint', true);
            
            app.logData = app.logData + newline + "Calibration JSON loaded successfully!";
            app.logData = app.logData + newline + "Click 'Preview 3D Cameras' to verify.";
            app.TextArea.Value = [splitlines(string(displayText)); app.logData];
            scroll(app.TextArea, 'bottom');
            
            % Enable save button
            app.SaveCalibrationButton.Enable = "on";
        end

        % Value changed function: chkDroneBody
        function chkDroneBodyValueChanged(app, event)
            value = app.chkDroneBody.Value;
            if value == 1
                app.show3D(app.drone);
            else
                app.showPlane(app.drone);
            end
        end

        % Value changed function: chkTrackingLines
        function chkTrackingLinesValueChanged(app, event)
           value = app.chkTrackingLines.Value;
            if value == 1
                app.wantTrackingLines = true;
            else
                app.wantTrackingLines = false;
                
                % --- THE FIX: Instantly delete the frozen lines ---
                delete(findobj(app.ThreeD, 'Tag', 'VisibilityLine'));
                
                % Optional: Clear the text area so it doesn't show stale 1s and 0s
                app.SeenTextArea.Value = {'Tracking lines disabled.'};
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if any data exists
            if isempty(app.timeLog)
                uialert(app.UIFigure, 'No tracking data to save.', 'Save Error');
                return;
            end
        
            % Ask user where to save
            [fileName, filePath] = uiputfile('*.mat', 'Save Tracking Log');
        
            if isequal(fileName,0)
                return; % User cancelled
            end
        
            fullFile = fullfile(filePath, fileName);
        
            % Create struct for saving
            logStruct = struct();
            logStruct.time = app.timeHist;
            logStruct.position = app.posHist;
            logStruct.velocity = app.velHist;
            logStruct.orientation = app.orientationHist;
            logStruct.angularVelocity = app.omegaHist;

            try
                save(fullFile, 'logStruct');
            catch exception
                uialert(app.UIFigure, 'Unsupported file format.', 'Save Error');
                return;
            end

            app.logData = app.logData + newline + "Tracking log saved: " + fileName;
            app.TextArea.Value = app.logData;
            scroll(app.TextArea,'bottom');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pnlSeen = uipanel(app.UIFigure);
            app.pnlSeen.Visible = 'off';
            app.SeenTextArea = uitextarea(app.pnlSeen);
            app.SeenTextArea.Value = {'Please start tracking to view information'};
            app.pnlTracking = uipanel(app.UIFigure);
            app.pnlTracking.Visible = 'off';
            app.StartTrackingButton = uibutton(app.pnlTracking, 'push');
            app.StartTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrackingButtonPushed, true);
            app.StartTrackingButton.Text = 'Start Tracking';
            app.PositionmLabel = uilabel(app.pnlTracking);
            app.PositionmLabel.Text = 'Position (m):';
            app.EditField = uieditfield(app.pnlTracking, 'text');
            app.VelocitymsLabel = uilabel(app.pnlTracking);
            app.VelocitymsLabel.Text = 'Velocity (m/s):';
            app.EditField_2 = uieditfield(app.pnlTracking, 'text');
            app.OrientationLabel = uilabel(app.pnlTracking);
            app.OrientationLabel.Text = 'Orientation:';
            app.EditField_3 = uieditfield(app.pnlTracking, 'text');
            app.radsLabel = uilabel(app.pnlTracking);
            app.radsLabel.Text = 'ω (rad/s):';
            app.EditField_4 = uieditfield(app.pnlTracking, 'text');
            app.xLabel = uilabel(app.pnlTracking);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.pnlTracking);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.pnlTracking);
            app.zLabel.Text = 'z';
            app.StopTrackingButton = uibutton(app.pnlTracking, 'push');
            app.StopTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrackingButtonPushed, true);
            app.StopTrackingButton.Text = 'Stop Tracking';
            app.SaveButton = uibutton(app.pnlTracking, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.EditField_5 = uieditfield(app.pnlTracking, 'text');
            app.EditField_6 = uieditfield(app.pnlTracking, 'text');
            app.EditField_7 = uieditfield(app.pnlTracking, 'text');
            app.EditField_8 = uieditfield(app.pnlTracking, 'text');
            app.EditField_9 = uieditfield(app.pnlTracking, 'text');
            app.EditField_10 = uieditfield(app.pnlTracking, 'text');
            app.EditField_11 = uieditfield(app.pnlTracking, 'text');
            app.EditField_12 = uieditfield(app.pnlTracking, 'text');
            app.pnlHeading = uipanel(app.UIFigure);
            app.MotionCaptureGUILabel = uilabel(app.pnlHeading);
            app.MotionCaptureGUILabel.Text = 'Motion Capture GUI';
            app.Image = uiimage(app.pnlHeading);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.jpg');
            app.pnlLog = uipanel(app.UIFigure);
            app.TextArea = uitextarea(app.pnlLog);
            app.LogLabel = uilabel(app.pnlLog);
            app.LogLabel.Text = 'Log';
            app.btnThreeDView = uibutton(app.UIFigure, 'push');
            app.btnThreeDView.ButtonPushedFcn = createCallbackFcn(app, @btnThreeDViewButtonPushed, true);
            app.btnThreeDView.Text = '3D View';
            app.btnPosGraphs = uibutton(app.UIFigure, 'push');
            app.btnPosGraphs.ButtonPushedFcn = createCallbackFcn(app, @btnPosGraphsButtonPushed, true);
            app.btnPosGraphs.Text = 'Position - Velocity Graphs';
            app.btnAngularVGraphs = uibutton(app.UIFigure, 'push');
            app.btnAngularVGraphs.ButtonPushedFcn = createCallbackFcn(app, @btnAngularVGraphsButtonPushed, true);
            app.btnAngularVGraphs.Text = 'Angular Velocity - Orientation Graphs';
            app.btnPathway = uibutton(app.UIFigure, 'push');
            app.btnPathway.ButtonPushedFcn = createCallbackFcn(app, @btnPathwayButtonPushed, true);
            app.btnPathway.Text = 'Trajectory';
            app.btnCalibrate = uibutton(app.UIFigure, 'push');
            app.btnCalibrate.ButtonPushedFcn = createCallbackFcn(app, @btnCalibrateButtonPushed, true);
            app.btnCalibrate.Text = 'Calibrate';
            app.pnlPathway = uipanel(app.UIFigure);
            app.pnlPathway.Visible = 'off';
            app.PathThreeD = uiaxes(app.pnlPathway);
            xlabel(app.PathThreeD, 'X')
            ylabel(app.PathThreeD, 'Y')
            zlabel(app.PathThreeD, 'Z')
            app.pnlPosTime = uipanel(app.UIFigure);
            app.pnlPosTime.Visible = 'off';
            app.XPosTime = uiaxes(app.pnlPosTime);
            xlabel(app.XPosTime, 'Time')
            ylabel(app.XPosTime, 'X')
            zlabel(app.XPosTime, 'Z')
            app.YPosTime = uiaxes(app.pnlPosTime);
            xlabel(app.YPosTime, 'Time')
            ylabel(app.YPosTime, 'Y')
            zlabel(app.YPosTime, 'Z')
            app.ZPosTime = uiaxes(app.pnlPosTime);
            xlabel(app.ZPosTime, 'Time')
            ylabel(app.ZPosTime, 'Z')
            zlabel(app.ZPosTime, 'Z')
            app.XVelTime = uiaxes(app.pnlPosTime);
            xlabel(app.XVelTime, 'Time')
            ylabel(app.XVelTime, 'X')
            zlabel(app.XVelTime, 'Z')
            app.YVelTime = uiaxes(app.pnlPosTime);
            xlabel(app.YVelTime, 'Time')
            ylabel(app.YVelTime, 'Y')
            zlabel(app.YVelTime, 'Z')
            app.ZVelTime = uiaxes(app.pnlPosTime);
            xlabel(app.ZVelTime, 'Time')
            ylabel(app.ZVelTime, 'Z')
            zlabel(app.ZVelTime, 'Z')
            app.PositionvsTimeGraphsLabel = uilabel(app.pnlPosTime);
            app.PositionvsTimeGraphsLabel.Text = 'Position vs Time Graphs';
            app.VelocityvsTimeGraphsLabel = uilabel(app.pnlPosTime);
            app.VelocityvsTimeGraphsLabel.Text = 'Velocity vs Time Graphs';
            app.pnlOmegaTime = uipanel(app.UIFigure);
            app.pnlOmegaTime.Visible = 'off';
            app.XOmegaTime = uiaxes(app.pnlOmegaTime);
            xlabel(app.XOmegaTime, 'Time')
            ylabel(app.XOmegaTime, 'X')
            zlabel(app.XOmegaTime, 'Z')
            app.YOmegaTime = uiaxes(app.pnlOmegaTime);
            xlabel(app.YOmegaTime, 'Time')
            ylabel(app.YOmegaTime, 'Y')
            zlabel(app.YOmegaTime, 'Z')
            app.ZOmegaTime = uiaxes(app.pnlOmegaTime);
            xlabel(app.ZOmegaTime, 'Time')
            ylabel(app.ZOmegaTime, 'Z')
            zlabel(app.ZOmegaTime, 'Z')
            app.XOrientTime = uiaxes(app.pnlOmegaTime);
            xlabel(app.XOrientTime, 'Time')
            ylabel(app.XOrientTime, 'X')
            zlabel(app.XOrientTime, 'Z')
            app.YOrientTime = uiaxes(app.pnlOmegaTime);
            xlabel(app.YOrientTime, 'Time')
            ylabel(app.YOrientTime, 'Y')
            zlabel(app.YOrientTime, 'Z')
            app.ZOrientTime = uiaxes(app.pnlOmegaTime);
            xlabel(app.ZOrientTime, 'Time')
            ylabel(app.ZOrientTime, 'Z')
            zlabel(app.ZOrientTime, 'Z')
            app.AngularVelocityvsTimeGraphsLabel = uilabel(app.pnlOmegaTime);
            app.AngularVelocityvsTimeGraphsLabel.Text = 'Angular Velocity vs Time Graphs';
            app.OrientationvsTimeGraphsLabel = uilabel(app.pnlOmegaTime);
            app.OrientationvsTimeGraphsLabel.Text = 'Orientation vs Time Graphs';
            app.btnTracking = uibutton(app.UIFigure, 'push');
            app.btnTracking.ButtonPushedFcn = createCallbackFcn(app, @btnTrackingButtonPushed, true);
            app.btnTracking.Text = 'Tracking';
            app.pnlViewing = uipanel(app.UIFigure);
            app.ThreeD = uiaxes(app.pnlViewing);
            xlabel(app.ThreeD, 'X')
            ylabel(app.ThreeD, 'Y')
            zlabel(app.ThreeD, 'Z')
            app.chkDroneBody = uicheckbox(app.pnlViewing);
            app.chkDroneBody.ValueChangedFcn = createCallbackFcn(app, @chkDroneBodyValueChanged, true);
            app.chkDroneBody.Text = 'Show 3D drone body';
            app.chkDroneBody.Value = true;
            app.chkTrackingLines = uicheckbox(app.pnlViewing);
            app.chkTrackingLines.ValueChangedFcn = createCallbackFcn(app, @chkTrackingLinesValueChanged, true);
            app.chkTrackingLines.Text = 'Show tracking lines';
            app.chkTrackingLines.Value = true;
            app.pnlCalibrate = uipanel(app.UIFigure);
            app.CameraPreviewButton = uibutton(app.pnlCalibrate, 'push');
            app.CameraPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CameraPreviewButtonPushed, true);
            app.CameraPreviewButton.Text = 'Camera Preview';
            app.DropDown = uidropdown(app.pnlCalibrate);
            app.DropDown.Items = {'Camera', 'Floor Plane'};
            app.DropDown.Value = 'Camera';
            app.LastCalibrationLabel = uilabel(app.pnlCalibrate);
            app.LastCalibrationLabel.Text = 'Last Calibration:';
            app.RatingLabel = uilabel(app.pnlCalibrate);
            app.RatingLabel.Text = 'Rating:';
            app.SaveCalibrationButton = uibutton(app.pnlCalibrate, 'push');
            app.SaveCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationButtonPushed, true);
            app.SaveCalibrationButton.Enable = 'off';
            app.SaveCalibrationButton.Text = 'Save Calibration';
            app.LoadCalibrationButton = uibutton(app.pnlCalibrate, 'push');
            app.LoadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationButtonPushed, true);
            app.LoadCalibrationButton.Text = 'Load Calibration';
            app.GetCalibrationDataButton = uibutton(app.pnlCalibrate, 'push');
            app.GetCalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetCalibrationDataButtonPushed, true);
            app.GetCalibrationDataButton.Text = 'Get Calibration Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MoCap_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end